classdef zi_closure_scheme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InputforReactionNetworkTab  matlab.ui.container.Tab
        NumberofreactionsEditFieldLabel  matlab.ui.control.Label
        num_reactions               matlab.ui.control.NumericEditField
        NumberofcomponentsLabel     matlab.ui.control.Label
        num_components              matlab.ui.control.NumericEditField
        react_stoich_table          matlab.ui.control.Table
        prod_stoich_table           matlab.ui.control.Table
        create_matrices_button      matlab.ui.control.Button
        kinetic_const_table         matlab.ui.control.Table
        generate_network_button     matlab.ui.control.Button
        name_table                  matlab.ui.control.Table
        Load_network_button         matlab.ui.control.Button
        Save_network_button         matlab.ui.control.Button
        ReactionsTab                matlab.ui.container.Tab
        ReactionNetworkLabel        matlab.ui.control.Label
        reactions_text              matlab.ui.control.TextArea
        warning_text                matlab.ui.control.Label
        InputforMomentEquationsTab  matlab.ui.container.Tab
        state_space_table           matlab.ui.control.Table
        label_input                 matlab.ui.control.Label
        MaximumOrderofMomentsLabel  matlab.ui.control.Label
        mu_high                     matlab.ui.control.NumericEditField
        produce_results_button      matlab.ui.control.Button
        moment_eq_ready             matlab.ui.control.Button
        run_lamp                    matlab.ui.control.Lamp
        state_space_button          matlab.ui.control.Button
        export_state_space_button   matlab.ui.control.Button
        not_max_order_check         matlab.ui.control.CheckBox
        Panel                       matlab.ui.container.Panel
        lagrange_table              matlab.ui.control.Table
        advanced_options            matlab.ui.control.CheckBox
        initial_guess_button        matlab.ui.control.Button
        InitialOrderofMomentsLabel  matlab.ui.control.Label
        mu_initial                  matlab.ui.control.NumericEditField
        MomentEquationsTab          matlab.ui.container.Tab
        mom_eq_dis                  matlab.ui.control.Button
        a_dis                       matlab.ui.control.Button
        a_prime_dis                 matlab.ui.control.Button
        a_values_table              matlab.ui.control.Table
        a_prime_values_table        matlab.ui.control.Table
        export_a_a_prime_button     matlab.ui.control.Button
        constant_vector_matrix      matlab.ui.control.Table
        mu_c_dis                    matlab.ui.control.Button
        ResultsTab                  matlab.ui.container.Tab
        prob_plot                   matlab.ui.control.UIAxes
        results_table               matlab.ui.control.Table
        export_results_button       matlab.ui.control.Button
        PlottingComponentLabel      matlab.ui.control.Label
        drop_down_menu_for_plot     matlab.ui.control.DropDown
        plot_new_component          matlab.ui.control.Button
        save_plot_button            matlab.ui.control.Button
        export_lagrange_multipliers_button  matlab.ui.control.Button
        LineStyleLabel              matlab.ui.control.Label
        plot_line_type              matlab.ui.control.DropDown
        LineColorLabel              matlab.ui.control.Label
        plot_line_color             matlab.ui.control.DropDown
        LineSizeEditFieldLabel      matlab.ui.control.Label
        plot_line_size              matlab.ui.control.NumericEditField
    end


    methods (Access = private)
        
        function [Gind,Tind] = BuildMreg(app,N,M,Sr,Sp,k,g)
        %The total number of moments involved given a linear network
        Tot = nchoosek(N+M,M);

        %extra is the number of additional moments necessary for nonlinear systems
        extra = 0;
        for i = 1:length(g)
            extra = max([length(factor(g(i))) extra]);
        end
        %pInd is prime indexing for all of the moments to be considered
        pInd = getMats(app,M+extra-1,N);

        Gind = [];
        Tind = [];

        Prime = primes(101);
        %Cr and Cp start as ones, these are the reactant and product coefficients
        %for the terms
        Cr = ones(size(Sr,1),1);
        Cp = ones(size(Sp,1),1);
        %Start up the regression for each of the components
        for n = 1:N

            p = Prime(n);
            [Gind_i,Tind_i] = RegCG(app,N,M,Sr,Sp,pInd,n,Sr(:,n),Sp(:,n),Cr,Cp,g,p,1);
            Gind = [Gind;Gind_i]; Tind = [Tind;Tind_i];
        end
        end
        %regCG
    
        %Reg here obviously refers to regression. This function takes into the
        %current information, produces the information for this specific step, then
        %regresses to get further information.
        
        %This portion of the function represents the novelty of the work here.
        %Essentially my program allows you to efficiently build the matrix by
        %building off of information already produced. Most analytical solutions
        %demand a much less efficient means of production
        
        %inputs: 	N - the number of components total
        %			M - the total number of moments
        %           Sr - the full reactant stoichiometries
        %           Sp - the full product stoichiometries
        %			pInd - the prime indexing
        %			Nmax - the current component being looked at
        %			R - the stoichiometric coefficients for reactants of Nmax
        %			P - the stoichiometric coefficients for products of Nmax
        %           Cp - the coefficients for the reactant terms for the moment
        %           Cr - the coefficients for the product terms for the moment
        %			G - The prime indexing of the moment for each term in the
        %               system
        %           g_curr - the current moment in prime index
        %           Mcurr - the current number of moments being considered
        
        %outputs:	Gind - a two column matrix indicating indexes in the matrix
        %			Tind - A set of coefficients matching Gind
        
        function [Gind,Tind] = RegCG(app,N,M,Sr,Sp,pInd,Nmax,R,P,Cr,Cp,G,g_curr,Mcurr)
        Gind = [];
        Kind = [];
        Cind = [];
        
        %get the primes for the current component
        Prime = primes(101); p = Prime(Nmax);
        %And then the index for the current moment
        Gx = getInd(app,g_curr,pInd);
        
        %At each step either the s variables or the G differential is
        %differentiated. When the s variables are the stoichiometries multiply by
        %the coefficients. Otherwise they do not
        Cr = [R.*Cr Cr];
        Cp = [P.*Cp Cp];
        
        %When the s variables are differentiated their order (R,P) is reduced
        R = [R-ones(size(R)) R];
        P = [P-ones(size(P)) P];
        
        %When the G differential is differentiated the indexing increases by p
        G = [G G*p];
        
        %setting the s variables to one means only those where Cp-Cr are nonzero
        %survive
        C = Cp-Cr;
        
        %This just produces and indexing for the kinetic constants
        K = ([1:size(Sr,1)]'*ones(1,size(C,2)));
        
        %Kind is then the kinetic constants used in the derivative
        if size(K,1)>1
            Kind = [Kind; K(C~=0)];
        else
            Kind = [Kind; K(C~=0)'];
        end
        %Cind is the coefficients
        if size(C,1)>1
            Cind = [Cind; C(C~=0)];
        else
           Cind = [Cind; C(C~=0)'];
        end
        
        %Gadd converts prime to normal indices
        Gadd = getInd(app,G(C~=0),pInd);
        if size(C,1)==1
            Gadd = Gadd';
        end
        
        %find only the unique terms to reduce the size of the final output matrix
        [Gadd Ignore Sort] = unique(Gadd);
        %Produce T which keeps track of the coefficients for each individual
        %reaction for each term.
        Tind = zeros(length(Gadd),size(Sr,1));
        for i = 1:length(Sort)
            Tind(Sort(i),Kind(i)) = Tind(Sort(i),Kind(i)) + Cind(i);
        end
        
        %Gind is the columns that match to Cind and Kind
        Gind = [Gind; Gx*ones(size(Gadd)) Gadd];
        
        %break out if the number of moments are completed
        if Mcurr==M
            return
        end
        
        %Else delve deeper. This first one is adding a moment to the current
        %component Nmax. Here R and P iare kept the same, the moment is increased
        [Gind_i,Tind_i] = RegCG(app,N,M,Sr,Sp,pInd,Nmax,R,P,Cr,Cp,G,g_curr*Prime(Nmax),Mcurr+1);
        Gind = [Gind;Gind_i]; Tind = [Tind;Tind_i];
        for i = Nmax+1:size(Sr,2)
            %For the other moments (from Nmax+1 to the end) R and P are set to the
            %new component stoichiometry. The moment is again increased
        	[Gind_i,Tind_i] = RegCG(app,N,M,Sr,Sp,pInd,i,Sr(:,i)*ones(1,size(Cr,2)),Sp(:,i)*ones(1,size(Cp,2)),Cr,Cp,G,g_curr*Prime(i),Mcurr+1);
            Gind = [Gind;Gind_i]; Tind = [Tind;Tind_i];
        end
        end
        
        %getInd
        
        %This subfunction takes in a prime representation of a moment
        %It converts this to a number based on the vector pInd
        
        %inputs: 	G - a vector of prime representations (arbitrary x 1)
        %			pInd - the prime index
        
        %outputs:	Gind - a normal indexing of the inputed matrix
        function Gind = getInd(app,G,pInd)
        
        Gind = zeros(size(G));
        %run through the vector
        for i = 1:length(G)
        	%for each use the find function to obtain a number index
            Gind(i) = find(pInd==G(i));
        end
        
        
        end
        
        function [pInd,mMat] = getMats(app,M,N)
        %Just placeholding for memory
        m = zeros(1,N);
        
        %Number of terms (same as number of terms in an M order polynomial with
        %N variables
        Tot = nchoosek(M+N,M);
        
        %Placeholding
        mMat = zeros(Tot,N);
        %run through for moments of order 1 and beyond
        for k = 2:Tot
        	%This algorithm is a bit weird. Basically, it adds terms to
        	%the end of the series until it hits M total
        	%i is always reset to the last place
            i = length(m);
            while sum(m) == M
        		%Here is sets m(i) to zero, increments i, then checks M again
                m(i) = 0; i = i - 1;
            end
            
        	%As long as i is somewhere along the line
            if i > 0
        		%add a term to it
                m(i) = m(i) + 1;
            end
            
        	%Then pop it in here
            mMat(k,:) = m;
        end
        %Now the whole thing is out of order. TotM puts the order as the first column
        %It is -mMat so that is sorts in decending order for those rows
        TotM = [sum(mMat,2) -mMat];
        
        %Then build in method for sorting rows
        TotM = sortrows(TotM);
        %We now have mMat
        mMat = -TotM(:,2:end);
        clear TotM
        
        %pInd is now fairly simple, get the primes
        prime = primes(1000);
        p = prime(1:N);
        
        %Produce a matrix of those primes in the right place
        pMat = ones(size(mMat,1),1)*p;
        %pInd involves element wise exponentiation
        pInd = prod(pMat.^mMat,2);
        clear pMat
        
        %mInd = [1:Tot]';
        end
        
        function str=recurse(app,moment_order,no_comp,names,str)
        global counter_rec
        global inticator_rec
        global name_counter
        if no_comp >= 1
           inticator_rec=inticator_rec+1;
           for i=moment_order:-1:0
              counter_rec(inticator_rec)=i;
              str=recurse(app,moment_order,no_comp-1,names,str);
           end
        else
           for j=length(counter_rec):-1:1
                if counter_rec(j)<=0
                    inticator_rec=j-1;
                else
                    break
                end
           end
           if sum(counter_rec)==moment_order
               if moment_order==0
                   name_counter=name_counter+1;
                   str{name_counter}='{ 1 }';
               else
                   count=1;
                   temp{count}='{ ';
                    for k=1:length(counter_rec)
                        if counter_rec(k)>1
                            count=count+1;
                            temp{count}=sprintf('%s^%d ',names(k,:),counter_rec(k));
                        elseif counter_rec(k)>0
                            count=count+1;
                            temp{count}=sprintf('%s ',names(k,:));
                        end
                    end
                    count=count+1;
                    temp{count}='}';
                    name_counter=name_counter+1;
                    str{name_counter}=char(cell2mat(temp));
               end
           end
        end
        end
        
        function [lam,fx,p,J] = OptSS(app,no_lower,Mat,mu_0,MatTotM,MatM,lam0)
        %
        lam0=lam0(2:end);
        lam = lam0;
        lamOld = lam;
        %
        S = getS(app,lam,MatM); %probability distribution 
        S = S/sum(S);
        %
        G = getG(app,S,MatTotM); %moments
        %
        R=Mat*G+mu_0; % residual
        %
        C = norm(R); % error
        Cold = C;
        %
        counter = 1;
        tol = 1E-10;
        %        
        while C > tol
            
            gnk = getGnk(app,S,MatTotM)-G*G'; % negative jacobian
            J=Mat*gnk(:,1:no_lower);
            %
            [Lj,Uj,Pj] = lu(J); 
            invJ=inv(Uj)*inv(Lj)*Pj; %inverse of the jacobian
            %
            dlam=invJ*R;
            lam_temp=lam+dlam; % new value of lamba
            %
            S = getS(app,lam_temp,MatM); 
            S = S/sum(S);
            %
            G = getG(app,S,MatTotM);
            %
            R=Mat*G+mu_0;
            %        
            C = norm(R);
            %            
            coeff = 1;
            %
        while C >= Cold || isnan(C)
            coeff = coeff/2;
            %
            lam_temp=lam+coeff*dlam;
            %
            %Recalculating
            S = getS(app,lam_temp,MatM);
            S = S/sum(S);
            %
            G = getG(app,S,MatTotM);
            %
            R=Mat*G+mu_0;
            %
            C = norm(R);
            %
            gnk = getGnk(app,S,MatTotM)-G*G';
            J=Mat*gnk(:,1:no_lower);
            %
            [Lj,Uj,Pj] = lu(J);
            invJ=inv(Uj)*inv(Lj)*Pj;
            %
            if coeff < 1E-16
                lam = lamOld;
                break;
            end
        end
        %
        if coeff < 1E-16
            break;
        end
        %
        Cold = C;
        %
        S = getS(app,lam_temp,MatM);
        S = S/sum(S);
        %
        G = getG(app,S,MatTotM);
        %
        R=Mat*G+mu_0;
        %
        C = norm(R);
        %
        lamOld=lam_temp;
        lam=lam_temp;
        %   
        counter = counter + 1;
        end
        %Get the probability distribution (p), the moments (fx), and the Jacobian (J)
        p = getS(app,lam,MatM);
        p = p/sum(p);
        %
        fx = getG(app,p,MatTotM);
        fx(2:size(fx)+1)=fx;
        fx(1)=sum(p);
        %
        gnk = -getGnk(app,S,MatTotM)+G*G';
        J=Mat*gnk(:,1:no_lower);
        %
        lam(2:size(lam)+1)=lam;
        lam(1)=log(sum(p));
        end
        
        %All of these are just matrix algebra, nothing too big.
        function S = getS(app,lam,MatM)
            S = -MatM*lam;
            S = exp(S);
            S(~isfinite(S))=0;
        end
        
        function G = getG(app,S,Mat)
            %G = (S'*Mat)';
            G = sum(Mat.*(S*ones(1,size(Mat,2))),1)';
        end
        
        function gnk = getGnk(app,S,MatM)
        %     for i = 1:size(MatM,2)
        %         gnk(i,:) = S'*(MatM.*(MatM(:,i)*ones(1,size(MatM,2))));
        %     end
            
            for i = 1:size(MatM,2)
                gnk(i,:) = sum(MatM.*(MatM(:,i)*ones(1,size(MatM,2))).*(S*ones(1,size(MatM,2))),1);
            end
        end
        
        function [lam,fx,p]=zi_closure(app,Sr,Sp,g,M,N,k,x,lam0)
        %
        [G T] = BuildMreg(app,N,M,Sr,Sp,k,g);
        T = T*k;
        %
        extra = 0;
        for i = 1:length(g)
            extra = max([length(factor(g(i))) extra]);
        end
        %
        Mat = (zeros(nchoosek(M+N,M),nchoosek(M+N+extra-1,M+extra-1)));
        for i = 1:size(G,1)
            Mat(G(i,1),G(i,2)) = Mat(G(i,1),G(i,2)) + T(i);
        end
        %
        [pInd MatTot] = getMats(app,M+extra-1,N);
        [pInd Ma] = getMats(app,M,N);
        Mextra = MatTot(size(Ma,1)+1:end,:);
        %
        space=cell(1,length(x));
        [space{:}]=ndgrid(x{:});
        %
        size_x=[1:length(x)];
        if length(x)>1
            size_x(1)=2;
            size_x(2)=1;
            for i=1:length(x)
                space{i}=permute(space{i},size_x);
            end
        end
        clear i        
        %
        for i = 1:size(MatTot,1)
            for j=1:length(x)
                x_local=space{j};
                Xm{j} = x_local.^0;
                for j1 = 1:MatTot(i,j)
                    Xm{j} = Xm{j}.*(x_local-j1+1);
                end
                clear x_local
            end
            temp = Xm{1};
            for k=1:length(x)-1
                temp=times(temp,Xm{k+1});
            end
            %
            if length(x)>2
                size_temp=[length(x):-1:1];
                size_temp(end)=2;
                size_temp(end-1)=1;
                temp=permute(temp,size_temp);
            end
            MatTotM(:,i) = temp(:); 
            clear Xm
        end
        MatM = MatTotM(:,1:size(Ma,1));
        MextraM = MatTotM(:,size(Ma,1)+1:end);
        %
        NT = null(Mat);
        ind = [1 size(NT,1)-size(NT,2)+2:size(NT,1)];
        for i = 1:length(ind)
            NT(:,i)=NT(:,i)/NT(ind(i),i);
            for j = [1:i-1 i+1:size(NT,2)]
                NT(:,j) = NT(:,j) - NT(:,i)*NT(ind(i),j);
            end
        end
        no_lower=nchoosek(M+N,M)-1;
        mu_0=Mat(2:end,1);
        Mat=Mat(2:end,2:end);
        MatM=MatM(:,2:end);
        MatTotM=MatTotM(:,2:end);
        warning('off', 'MATLAB:nearlySingularMatrix')
        [lam,fx,p,J] = OptSS(app,no_lower,Mat,mu_0,MatTotM,MatM,lam0);
        warning('on', 'MATLAB:nearlySingularMatrix')
        end
    end


    methods (Access = private)

        % Button pushed function: create_matrices_button
        function matrices_ready(app, event)
        reactions_num = app.num_reactions.Value;
        components_num = app.num_components.Value;
        %
        Alphabet=('A':'Z').';
        [I,J]=meshgrid(Alphabet,Alphabet);
        alphabet=char(Alphabet,[I(:),J(:)]);
        clear I J Alphabet
        alphabet=cellstr(alphabet);
            %         
        app.name_table.Data=[alphabet(1:components_num) alphabet(1:components_num)];
        app.name_table.ColumnEditable =[false true];
            %        
        alphabet(2:end+1)=alphabet;      
        %
        alphabet(1)=cellstr(app.react_stoich_table.ColumnName{1});
        app.react_stoich_table.ColumnName=alphabet(1:components_num+1);
        %
        alphabet(1)=cellstr(app.prod_stoich_table.ColumnName{1});
        app.prod_stoich_table.ColumnName=alphabet(1:components_num+1);
        %             
        for i=1:reactions_num
            num_colms{i,1} = sprintf('Reaction %d',i);
        end           
        %                         
        app.react_stoich_table.Data=[num_colms num2cell(zeros(reactions_num,components_num))];
        app.prod_stoich_table.Data=[num_colms num2cell(zeros(reactions_num,components_num))];
        app.kinetic_const_table.Data=[num_colms num2cell(zeros(reactions_num,1))];
        %         
        app.react_stoich_table.ColumnEditable = [false true(1,components_num)];
        app.prod_stoich_table.ColumnEditable = [false true(1,components_num)];
        app.kinetic_const_table.ColumnEditable =[false true];
        end

        % Button pushed function: generate_network_button
        function network_ready(app, event)
            format short
            set(groot, 'DefaultTextInterpreter', 'none')
            set(groot, 'DefaultLegendInterpreter', 'none')
            %
            reactions_num = app.num_reactions.Value;
            components_num = app.num_components.Value;
            reactants=cell2table(app.react_stoich_table.Data);
            products=cell2table(app.prod_stoich_table.Data);
            kinetic_const=cell2table(app.kinetic_const_table.Data);
            alphabet=char(app.name_table.Data(:,2));
            %             
            % make raction network
            %              
            app.reactions_text.Value={''};
            %             
            reactants=table2array(reactants(:,2:components_num+1));
            products=table2array(products(:,2:components_num+1));
            kinetic_const=table2array(kinetic_const(:,2));
            for i=1:reactions_num
                temp=reactants(i,:);
                location=find(temp~=0);
                for j=1:length(location)
                    str{2*j-1}=sprintf('%d %s',temp(location(j)),alphabet(location(j),:));
                    str{2*j}=' + ';
                end
            %                 
                if isempty(location)
                    str{1}='0';
                    str{2}=' --> ';
                    j=1;
                else
                    str{2*j}=' --> ';
                end
            %                 
                temp=products(i,:);
                location=find(temp~=0);
                for k=j+1:length(location)+j
                    str{2*k-1}=sprintf('%d %s',temp(location(k-j)),alphabet(location(k-j),:));
                    str{2*k}=' + ';
                end
            %                 
                if isempty(location)
                    str{2*j+1}='0';
                    k=j+1/2;
                else
                    str{2*k}='';
                end
            %                 
                str{2*k+1}=sprintf(', k_%d = %.6f',i,kinetic_const(i));
            %                 
                clear k j temp location
            app.reactions_text.Value{i}=char(cell2mat(str));
            clear str
            end
            clear i
            %             
            %make lagrange table
            %
            str='';
            global name_counter
            name_counter=0;
            for h=0:2
                global counter_rec
                global inticator_rec
                inticator_rec=0;
                counter_rec=zeros(1,components_num);
                str=recurse(app,h,components_num,alphabet,str);
            end
            clear name_counter counter_rec inticator_rec
            %             
            app.lagrange_table.Data=[str(1:end)' num2cell(zeros(size(str,2),1))];
            app.lagrange_table.ColumnEditable =[false true];
            %             
            %make state space table
            %             
            alphabet=cellstr(alphabet);
            %
            app.state_space_table.Data=[alphabet(1:components_num) num2cell(zeros(components_num,2))];
            app.state_space_table.ColumnEditable =[false true true];
            %
            %update older table component names
            %
            app.drop_down_menu_for_plot.Items = alphabet;
            %
            alphabet(2:end+1)=alphabet;
            %
            alphabet(1)=cellstr(app.react_stoich_table.ColumnName{1});
            app.react_stoich_table.ColumnName=alphabet(1:components_num+1);
            %
            alphabet(1)=cellstr(app.prod_stoich_table.ColumnName{1});
            app.prod_stoich_table.ColumnName=alphabet(1:components_num+1);
        end

        % Value changed function: mu_initial
        function put_initial_guess(app, event)
            initial_mu_size = app.mu_initial.Value;
            components_num = app.num_components.Value;
            alphabet=char(app.name_table.Data(:,2));
            %
            str='';
            global name_counter
            name_counter=0;
            for h=0:initial_mu_size
                global counter_rec
                global inticator_rec
                inticator_rec=0;
                counter_rec=zeros(1,components_num);
                str=recurse(app,h,components_num,alphabet,str);
            end
            clear name_counter counter_rec inticator_rec
            %             
            app.lagrange_table.Data=[str(1:end)' num2cell(zeros(size(str,2),1))];
            app.lagrange_table.ColumnEditable =[false true];
        end

        % Button pushed function: produce_results_button
        function produce_results_buttonPushed(app, event)
            app.run_lamp.Color = [1 0 0];
            %
            N = app.num_components.Value;
            Sr = cell2table(app.react_stoich_table.Data);
            Sp = cell2table(app.prod_stoich_table.Data);
            k = cell2mat(app.kinetic_const_table.Data(:,2));
            M_init = app.mu_initial.Value;
            M_max = app.mu_high.Value;
            M_step = 1;
            reactions_num = app.num_reactions.Value;
            components_num = app.num_components.Value;
            alphabet=char(app.name_table.Data(:,2));
            %
            lam0=cell2mat(app.lagrange_table.Data(:,2));
            %
            Sr=table2array(Sr(:,2:N+1));
            Sp=table2array(Sp(:,2:N+1));
            %
            for i=1:components_num
                x{i}=[cell2mat(app.state_space_table.Data(i,2)):cell2mat(app.state_space_table.Data(i,3))];
            end
            %
            % generate g
            %
            all_prime=primes(3e3);
            prime_here=all_prime(1:N);
            clear all_prime
            % 
            g=prod([prime_here.^Sr ones(reactions_num,1)]')';
            clear prime_here
            %
            %solve for probability
            %
            global p
            p=[];
            fx_old=zeros(length(lam0),1);
            for M=M_init:M_step:M_max
                [lam,fx,p]=zi_closure(app,Sr,Sp,g,M,N,k,x,lam0);
                lam0=[lam;zeros(nchoosek(M+M_step+N,M+M_step)-nchoosek(M+N,M),1)];
                if app.not_max_order_check.Value==1
                    if norm((fx_old-fx(1:length(fx_old),1))./fx(1:length(fx_old),1))<1e-3
                        break
                    else
                        fx_old=fx;
                    end
                end
            end
            %
            % reshape p, reshape fills column wise, and here we need row wise, so reshpape and permute have to be employeed
            %
            
            global prob_d
            prob_d=[];
            if length(x)>1
                counter=0;
                for i=length(x):-1:1
                    counter=counter+1;
                    reshape_vector(counter)=length(x{i});
                end
                clear counter i
            %
                prob_d=reshape(p,reshape_vector);
                prob_d=permute(prob_d,[length(x):-1:1]);
                sum_p=prob_d;
                for i=2:length(x)
                    sum_p=sum(sum_p,i);
                end
            else
                prob_d=p;
                sum_p=p;
            end
            %
            %plot results
            %
            global plotted_comp
            plotted_comp=[];
            plotted_comp=plot(app.prob_plot,x{1},sum_p,'LineWidth',app.plot_line_size.Value,'LineStyle',app.plot_line_type.Value(end-1:end),'Color',app.plot_line_color.Value);
            title(app.prob_plot, '');
            xlabel(app.prob_plot, alphabet(1,:));
            ylabel(app.prob_plot, 'Probability');  
            %
            %display results
            %
            app.results_table.Data=[fx(1:length(lam)) lam];
            %
            alphabet=char(app.name_table.Data(:,2));
            str='';
            global name_counter
            name_counter=0;
            for h=0:M_max
                global counter_rec
                global inticator_rec
                inticator_rec=0;
                counter_rec=zeros(1,components_num);
                str=recurse(app,h,components_num,alphabet,str);
            end
            clear name_counter counter_rec inticator_rec
            %
            app.results_table.RowName = str;
            %
            app.run_lamp.Color = [0 1 0];
        end

        % Button pushed function: moment_eq_ready
        function show_moment_eq(app, event)
            reactions_num = app.num_reactions.Value;
            N = app.num_components.Value;
            Sr = cell2table(app.react_stoich_table.Data);
            Sp = cell2table(app.prod_stoich_table.Data);
            k = cell2mat(app.kinetic_const_table.Data(:,2));
            M = app.mu_high.Value;
            alphabet=char(app.name_table.Data(:,2));
            components_num = app.num_components.Value;
            %             
            Sr=table2array(Sr(:,2:N+1));
            Sp=table2array(Sp(:,2:N+1));
            %             tot_m = nchoosek(M+N,M);
            %             
            % generate g
            all_prime=primes(3e3);
            prime_here=all_prime(1:N);
            clear all_prime
            % 
            g=prod([prime_here.^Sr ones(reactions_num,1)]')';
            clear prime_here
            %             
            [G,T] = BuildMreg(app,N,M,Sr,Sp,k,g);
            T = T*k;
            
            extra = 0;
            for i = 1:length(g)
            extra = max([length(factor(g(i))) extra]);
        end
        
        Mat = (zeros(nchoosek(M+N,M),nchoosek(M+N+extra-1,M+extra-1)));
        for i = 1:size(G,1)
            Mat(G(i,1),G(i,2)) = Mat(G(i,1),G(i,2)) + T(i);
        end
        %
        %moment order
        %
        str='';
        global name_counter
        name_counter=0;
        for h=0:M
            global counter_rec
            global inticator_rec
            inticator_rec=0;
            counter_rec=zeros(1,components_num);
            str=recurse(app,h,components_num,alphabet,str);
        end
        clear name_counter counter_rec inticator_rec
        %
        app.a_values_table.Data=[str(2:end);num2cell(Mat(2:end,2:nchoosek(M+N,M)))];
        app.a_values_table.RowName = [' ' str(2:end)];
        app.a_prime_values_table.RowName = [' ' str(2:end)];
        app.constant_vector_matrix.Data=[num2cell(Mat(2:end,1))];
        app.constant_vector_matrix.RowName = [str(2:end)];
        clear str
        %
        if extra-1>0;
            str1='';
            global name_counter
            name_counter=0;
            for h=M+1:M+extra-1
                global counter_rec
                global inticator_rec
                inticator_rec=0;
                counter_rec=zeros(1,components_num);
                str1=recurse(app,h,components_num,alphabet,str1);
            end
            clear name_counter counter_rec inticator_rec
            %
            app.a_prime_values_table.Data=[str1;num2cell(Mat(2:end,nchoosek(M+N,M)+1:end))];
            clear str1
        end
        end

        % Button pushed function: Load_network_button
        function load_network(app, event)
            [filename,pathname,filterindex] = uigetfile({'*.mat';'*.tsv'});
            %
            if filterindex==2
                % load tsv file
                A=textscan(fopen(fullfile(pathname,filename),'r'),'%s',1000,'Delimiter','\n');
                B=A{1,1};
                %
                spaces=find(ismember(B,'')==1);
                %
                %find reactants names 
                %
                spaces_comp=find(contains(B,'TableType="Compound"')==1);
                comp_start=spaces_comp+2;
                f=find(spaces>comp_start);
                comp_end=spaces(f(1))-1;
                clear f
                for i=comp_start:comp_end
                    line=B{i};
                    line_spaces=find(isspace(line)==1);
                    component_names(i-comp_start+1,1)=cellstr(line(1:line_spaces(1)-1));
                    clear line line_spaces
                end
                %
                % find reaction table
                %
                spaces_re=find(contains(B,'TableType="Reaction"')==1);
                reac_start=spaces_re+2;
                f=find(spaces>reac_start);
                reac_end=spaces(f(1))-1;
                clear f
                counter1=0;
                for i=reac_start:reac_end
                    line=B{i};
                    line_spaces=find(isspace(line)==1);
                    line_equal=strfind(line,'<=>');
                    %
                    f=find(line_spaces<line_equal);
                    %
                    counter1=counter1+1;
                    reacting(counter1,1)=string(line(line_spaces(f(end-1))+1:line_equal-2));
                    %
                    counter=1;
                    for j=1:length(f)
                        if string(line(line_spaces(f(end-counter-1))+1:line_spaces(f(end-counter))-1))=='+'
                            counter=counter+1;
                            reacting(counter1,j+1)=string(line(line_spaces(f(end-counter-1))+1:line_spaces(f(end-counter))-1));
                            counter=counter+1;
                        else
                            break
                        end
                    end
                    clear f counter j
                    %
                    %
                    f=find(line_spaces>line_equal);
                    %
                    producing(counter1,1)=string(line(line_equal+4:line_spaces(f(2))-1));
                    %
                    counter=1;
                    for j=1:length(f)
                        if string(line(line_spaces(f(counter+1))+1:line_spaces(f(counter+2))-1))=='+'
                            counter=counter+1;
                            producing(counter1,j+1)=string(line(line_spaces(f(counter+1))+1:line_spaces(f(counter+2))-1));
                            counter=counter+1;
                        else
                            break
                        end
                    end
                    clear f counter j
                    %
                    if contains(line,'True')==1
                        counter1=counter1+1;
                        reacting(counter1,:)=producing(counter1-1,:);
                        producing(counter1,:)=reacting(counter1-1,:);
                    end
                    %
                    clear line line_spaces line_equal line_plus
                end
                clear i
                %
                %constract reaction table
                %
                for i=1:length(component_names)
                    reactants_table(:,i)=double(component_names{i}==reacting);
                    products_table(:,i)=double(component_names{i}==producing);
                end
                clear i
                %
                %find kinetic constatns
                %
                spaces_const=find(contains(B,'TableType="Quantity"')==1);
                const_start=spaces_const+2;
                f=find(spaces>const_start);
                const_end=spaces(f(1))-1;
                clear f
                for i=const_start:const_end
                    line=B{i};
                    line_spaces=find(isspace(line)==1);
                    kinetic_constants(i-const_start+1,1)=str2num(line(line_spaces(2)+1:line_spaces(3)-1));
                    clear line line_spaces
                end
                %
                % input data
                %
                app.num_components.Value = size(component_names,1);
                components_num = size(component_names,1);
                %
                % fill name table
                %
                Alphabet=('A':'Z').';
                [I,J]=meshgrid(Alphabet,Alphabet);
                alphabet=char(Alphabet,[I(:),J(:)]);
                clear I J Alphabet
                alphabet=cellstr(alphabet);         
                app.name_table.Data=[alphabet(1:components_num) component_names];
                app.name_table.ColumnEditable =[false true];
                %
                app.num_reactions.Value = size(reacting,1);
                %
                % fill reactants table
                %
                alphabet(2:end+1)=alphabet;
                reactions_num=size(reacting,1);
                %
                alphabet(1)=cellstr(app.react_stoich_table.ColumnName{1});
                app.react_stoich_table.ColumnName=alphabet(1:components_num+1);
                %
                alphabet(1)=cellstr(app.prod_stoich_table.ColumnName{1});
                app.prod_stoich_table.ColumnName=alphabet(1:components_num+1);
                %             
                for i=1:reactions_num
                    num_colms{i,1} = sprintf('Reaction %d',i);
                end     
                %
                app.react_stoich_table.Data=[num_colms num2cell(reactants_table)];
                app.react_stoich_table.ColumnEditable = [false true(1,size(component_names,1))];
                %
                app.prod_stoich_table.Data = [num_colms num2cell(products_table)];
                app.prod_stoich_table.ColumnEditable = [false true(1,size(component_names,1))];
                %
                % fill kinetic constants table
                %
                app.kinetic_const_table.Data=[num_colms num2cell(kinetic_constants(1:reactions_num))];
                app.kinetic_const_table.ColumnEditable =[false true];
            else
                load(fullfile(pathname,filename))
                %
                app.react_stoich_table.Data = reactants_table;
                app.prod_stoich_table.Data = products_table;
                app.kinetic_const_table.Data = kinetic_constants;
                app.name_table.Data = component_names;
                %
                app.num_reactions.Value = size(kinetic_constants,1);
                app.num_components.Value = size(component_names,1);
                %
                app.react_stoich_table.ColumnEditable = [false true(1,size(component_names,1))];
                app.prod_stoich_table.ColumnEditable = [false true(1,size(component_names,1))];
                app.kinetic_const_table.ColumnEditable =[false true];
                app.name_table.ColumnEditable =[false true];
            end
        end

        % Button pushed function: Save_network_button
        function save_network(app, event)
            reactants_table = app.react_stoich_table.Data;
            products_table = app.prod_stoich_table.Data;
            kinetic_constants = app.kinetic_const_table.Data;
            component_names=app.name_table.Data;
            %
            % save them
            %
            uisave({'reactants_table','products_table','kinetic_constants','component_names'},'network');
        end

        % Button pushed function: initial_guess_button
        function load_lagrange_guess(app, event)
            [filename,pathname] = uigetfile('*.mat');
            %
            lagrange_guess=num2cell(struct2array(load(fullfile(pathname,filename))));
            app.lagrange_table.Data(1:length(lagrange_guess),2)=lagrange_guess;
        end

        % Button pushed function: export_a_a_prime_button
        function export_matrices(app, event)
            A=[app.a_values_table.RowName app.a_values_table.Data];%cell2mat(app.a_values_table.Data);%(2:end,:));
            mu_c=[app.constant_vector_matrix.RowName app.constant_vector_matrix.Data];%cell2mat(app.constant_vector_matrix.Data);
            if size(app.a_prime_values_table.Data,1)>0
                A_prime=[app.a_prime_values_table.RowName app.a_prime_values_table.Data];%cell2mat(app.a_prime_values_table.Data(2:end,:));
            else
                A_prime=[];
            end
            %
            % save them
            %
            %             uisave({'A','A_prime','mu_c'},'moment_eq_matrices');
            [filename,pathname,filterIndex]=uiputfile({'*.mat';'*.xlsx'},'Save Moment Equation Matrices','moment_eq_matrices');
            full_name=fullfile(pathname,filename);
            if filterIndex==1 | filterIndex==3
                save(full_name,'A','A_prime','mu_c');
            elseif filterIndex==2
                xlswrite(full_name,A,'A');
                xlswrite(full_name,A_prime,'A_prime');
                xlswrite(full_name,mu_c,'mu_c');
            end
            %             saveas(newfig,full_name);
        end

        % Button pushed function: export_results_button
        function export_results(app, event)
            global prob_d
            global p
            probability_distribution=prob_d;
            %
            kinetic_constants =[app.reactions_text.Value app.kinetic_const_table.Data(:,2)];
            %
            lagrange_multipliers_initial_guess=app.lagrange_table.Data;%cell2mat
            %
            for i=1:app.num_components.Value
                state_space{1,i}=[cell2mat(app.state_space_table.Data(i,2)):cell2mat(app.state_space_table.Data(i,3))];
                state_space{2,i}=app.name_table.Data(i,2);
            end
            stationary_moments=[app.results_table.RowName(1:size(app.results_table.Data,1)) num2cell(app.results_table.Data(:,1))];
            lagrange_multipliers=[app.results_table.RowName(1:size(app.results_table.Data,1)) num2cell(app.results_table.Data(:,2))];
            components_order=app.name_table.Data(:,2);
            %
            % save them
            %
            [filename,pathname,filterIndex]=uiputfile({'*.mat';'*.xlsx'},'Save Results','results');
            full_name=fullfile(pathname,filename);
            if filterIndex==1 | filterIndex==3
                save(full_name,'stationary_moments','lagrange_multipliers','probability_distribution',...
                'state_space','kinetic_constants','lagrange_multipliers_initial_guess','components_order');
            elseif filterIndex==2
                xlswrite(full_name,components_order,'components');
                xlswrite(full_name,kinetic_constants,'kinetic_constants');
                xlswrite(full_name,app.state_space_table.Data,'state_space');
                xlswrite(full_name,lagrange_multipliers_initial_guess,'lagrange_initial');
                xlswrite(full_name,lagrange_multipliers,'lagrange_multipliers');
                xlswrite(full_name,stationary_moments,'stationary_moments');
                xlswrite(full_name,probability_distribution(:,:),'probability_distribution');
            end
        end

        % Button pushed function: plot_new_component
        function button_change_plot_pushed(app, event)
            global prob_d
            plot_comp=app.drop_down_menu_for_plot.Value;
            alphabet=cellstr(char(app.name_table.Data(:,2)));
            components_num = app.num_components.Value;
            %
            % find position of component
            %
            [truefalse, posit] = ismember(plot_comp,alphabet);
            %
            % plot it
            %
            for i=1:components_num
                x{i}=[cell2mat(app.state_space_table.Data(i,2)):cell2mat(app.state_space_table.Data(i,3))];
            end
            %
            sum_p=prob_d;
            comp_no_plot=[1:components_num];
            comp_no_plot(posit)=[];
            for i=comp_no_plot
                sum_p=sum(sum_p,i);
            end
            sum_p=squeeze(sum_p);
            global plotted_comp
            plotted_comp=[];
            plotted_comp=plot(app.prob_plot,x{posit},sum_p,'LineWidth',app.plot_line_size.Value,'LineStyle',app.plot_line_type.Value(end-1:end),'Color',app.plot_line_color.Value);
            title(app.prob_plot, '');
            xlabel(app.prob_plot, plot_comp);
            ylabel(app.prob_plot, 'Probability');
        end

        % Button pushed function: save_plot_button
        function save_plot_button_pushed(app, event)
            global plotted_comp
            [filename,pathname]=uiputfile({'*.fig';'*.jpg';'*.png';'*.eps'},'Save image','image');
            full_name=fullfile(pathname,filename);
            newfig=figure;
            plot(plotted_comp.XData,plotted_comp.YData,'LineWidth',app.plot_line_size.Value,'LineStyle',app.plot_line_type.Value(end-1:end),'Color',app.plot_line_color.Value);
            xlabel(app.prob_plot.XLabel.String);ylabel(app.prob_plot.YLabel.String);
            saveas(newfig,full_name);
            close(newfig)
        end

        % Button pushed function: state_space_button
        function state_space_button_pushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            %
            state_space_limits=num2cell(struct2array(load(fullfile(pathname,filename))));
            app.state_space_table.Data(:,2:3)=state_space_limits;
        end

        % Button pushed function: export_state_space_button
        function export_state_space_button_pushed(app, event)
            state_space_ma=cell2mat(app.state_space_table.Data(:,2:3));
            %
            % state space
            %
            uisave({'state_space_ma'},'state_space');
            clear state_space_ma
        end

        % Button pushed function: export_lagrange_multipliers_button
        function export_lagrange_multipliers_button_pushed(app, event)
            lagrange_multipliers=app.results_table.Data(:,2);
            %
            % save them
            %
            uisave({'lagrange_multipliers'},'lagrange_multipliers');
        end

        % Value changed function: advanced_options
        function advanced_options_checked(app, event)
            if app.advanced_options.Value==1
                app.mu_initial.Enable = 'on';
                app.lagrange_table.Enable = 'on';
                app.initial_guess_button.Enable = 'on';
            else
                app.mu_initial.Enable = 'off';
                app.lagrange_table.Enable = 'off';
                app.initial_guess_button.Enable = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputforReactionNetworkTab = uitab(app.TabGroup);
            app.InputforReactionNetworkTab.Title = 'Input for Reaction Network';
            app.InputforReactionNetworkTab.Units = 'pixels';
            app.NumberofreactionsEditFieldLabel = uilabel(app.InputforReactionNetworkTab);
            app.NumberofreactionsEditFieldLabel.Text = 'Number of reactions';
            app.num_reactions = uieditfield(app.InputforReactionNetworkTab, 'numeric');
            app.num_reactions.Limits = [0 99];
            app.num_reactions.ValueDisplayFormat = '%.0f';
            app.NumberofcomponentsLabel = uilabel(app.InputforReactionNetworkTab);
            app.NumberofcomponentsLabel.Text = 'Number of components';
            app.num_components = uieditfield(app.InputforReactionNetworkTab, 'numeric');
            app.num_components.Limits = [0 99];
            app.num_components.ValueDisplayFormat = '%.0f';
            app.react_stoich_table = uitable(app.InputforReactionNetworkTab);
            app.react_stoich_table.ColumnName = {'Reactant'};
            app.react_stoich_table.RowName = {};
            app.prod_stoich_table = uitable(app.InputforReactionNetworkTab);
            app.prod_stoich_table.ColumnName = {'Product'};
            app.prod_stoich_table.RowName = {};
            app.create_matrices_button = uibutton(app.InputforReactionNetworkTab, 'push');
            app.create_matrices_button.ButtonPushedFcn = createCallbackFcn(app, @matrices_ready, true);
            app.create_matrices_button.Text = 'Generate Stoichiometric Matrices';
            app.kinetic_const_table = uitable(app.InputforReactionNetworkTab);
            app.kinetic_const_table.ColumnName = {'Reaction'; 'Kinetic Constant'};
            app.kinetic_const_table.RowName = {};
            app.generate_network_button = uibutton(app.InputforReactionNetworkTab, 'push');
            app.generate_network_button.ButtonPushedFcn = createCallbackFcn(app, @network_ready, true);
            app.generate_network_button.Text = 'Matrices ready';
            app.name_table = uitable(app.InputforReactionNetworkTab);
            app.name_table.ColumnName = {'Default name'; 'New name'};
            app.name_table.RowName = {};
            app.Load_network_button = uibutton(app.InputforReactionNetworkTab, 'push');
            app.Load_network_button.ButtonPushedFcn = createCallbackFcn(app, @load_network, true);
            app.Load_network_button.Text = 'Load a network';
            app.Save_network_button = uibutton(app.InputforReactionNetworkTab, 'push');
            app.Save_network_button.ButtonPushedFcn = createCallbackFcn(app, @save_network, true);
            app.Save_network_button.Text = 'Save network';
            app.ReactionsTab = uitab(app.TabGroup);
            app.ReactionsTab.Title = 'Reactions';
            app.ReactionsTab.Units = 'pixels';
            app.ReactionNetworkLabel = uilabel(app.ReactionsTab);
            app.ReactionNetworkLabel.Text = 'Reaction Network';
            app.reactions_text = uitextarea(app.ReactionsTab);
            app.reactions_text.Editable = 'off';
            app.reactions_text.Value = {'All the reactions will be displayed here'};
            app.warning_text = uilabel(app.ReactionsTab);
            app.warning_text.Text = {'If the reaction network is not correct,'; 'please make corrections at the previous tab'};
            app.InputforMomentEquationsTab = uitab(app.TabGroup);
            app.InputforMomentEquationsTab.Title = 'Input for Moment Equations';
            app.InputforMomentEquationsTab.Units = 'pixels';
            app.state_space_table = uitable(app.InputforMomentEquationsTab);
            app.state_space_table.ColumnName = {'Component'; 'Minimum Value'; 'Maximum Value'};
            app.state_space_table.RowName = {};
            app.label_input = uilabel(app.InputforMomentEquationsTab);
            app.label_input.Text = 'Please enter components state space';
            app.MaximumOrderofMomentsLabel = uilabel(app.InputforMomentEquationsTab);
            app.MaximumOrderofMomentsLabel.Text = 'Maximum Order of  Moments';
            app.mu_high = uieditfield(app.InputforMomentEquationsTab, 'numeric');
            app.mu_high.Limits = [0 Inf];
            app.mu_high.ValueDisplayFormat = '%.0f';
            app.mu_high.Value = 6;
            app.produce_results_button = uibutton(app.InputforMomentEquationsTab, 'push');
            app.produce_results_button.ButtonPushedFcn = createCallbackFcn(app, @produce_results_buttonPushed, true);
            app.produce_results_button.Text = 'Solve for the steady state probability distribution';
            app.moment_eq_ready = uibutton(app.InputforMomentEquationsTab, 'push');
            app.moment_eq_ready.ButtonPushedFcn = createCallbackFcn(app, @show_moment_eq, true);
            app.moment_eq_ready.Text = 'Show moment equations';
            app.run_lamp = uilamp(app.InputforMomentEquationsTab);
            app.state_space_button = uibutton(app.InputforMomentEquationsTab, 'push');
            app.state_space_button.ButtonPushedFcn = createCallbackFcn(app, @state_space_button_pushed, true);
            app.state_space_button.Text = {'Import State'; 'Space Limits'};
            app.export_state_space_button = uibutton(app.InputforMomentEquationsTab, 'push');
            app.export_state_space_button.ButtonPushedFcn = createCallbackFcn(app, @export_state_space_button_pushed, true);
            app.export_state_space_button.Text = {'Export State'; 'Space Limits'};
            app.not_max_order_check = uicheckbox(app.InputforMomentEquationsTab);
            app.not_max_order_check.Text = {'Terminate calculation'; 'before maximum order '; 'is reached'};
            app.not_max_order_check.Value = true;
            app.Panel = uipanel(app.InputforMomentEquationsTab);
            app.lagrange_table = uitable(app.Panel);
            app.lagrange_table.ColumnName = {'Lagrange Multiplier for'; 'Initial guess'};
            app.lagrange_table.RowName = {};
            app.lagrange_table.Enable = 'off';
            app.advanced_options = uicheckbox(app.Panel);
            app.advanced_options.ValueChangedFcn = createCallbackFcn(app, @advanced_options_checked, true);
            app.advanced_options.Text = 'Advanced  Options (see Manual)';
            app.initial_guess_button = uibutton(app.Panel, 'push');
            app.initial_guess_button.ButtonPushedFcn = createCallbackFcn(app, @load_lagrange_guess, true);
            app.initial_guess_button.Enable = 'off';
            app.initial_guess_button.Text = {'Load'; 'Initial Guess'};
            app.InitialOrderofMomentsLabel = uilabel(app.Panel);
            app.InitialOrderofMomentsLabel.Enable = 'off';
            app.InitialOrderofMomentsLabel.Text = 'Initial Order of Moments';
            app.mu_initial = uieditfield(app.Panel, 'numeric');
            app.mu_initial.ValueChangedFcn = createCallbackFcn(app, @put_initial_guess, true);
            app.mu_initial.Limits = [1 Inf];
            app.mu_initial.ValueDisplayFormat = '%.0f';
            app.mu_initial.Enable = 'off';
            app.mu_initial.Value = 2;
            app.MomentEquationsTab = uitab(app.TabGroup);
            app.MomentEquationsTab.Title = 'Moment Equations';
            app.MomentEquationsTab.Units = 'pixels';
            app.mom_eq_dis = uibutton(app.MomentEquationsTab, 'push');
            app.mom_eq_dis.Icon = 'moment_eq.gif';
            app.mom_eq_dis.Text = '';
            app.a_dis = uibutton(app.MomentEquationsTab, 'push');
            app.a_dis.Icon = 'A_matrix.gif';
            app.a_dis.Text = '';
            app.a_prime_dis = uibutton(app.MomentEquationsTab, 'push');
            app.a_prime_dis.Icon = 'A_prime_matrix.gif';
            app.a_prime_dis.Text = '';
            app.a_values_table = uitable(app.MomentEquationsTab);
            app.a_values_table.ColumnName = '';
            app.a_values_table.RowName = {};
            app.a_prime_values_table = uitable(app.MomentEquationsTab);
            app.a_prime_values_table.ColumnName = '';
            app.a_prime_values_table.RowName = {};
            app.export_a_a_prime_button = uibutton(app.MomentEquationsTab, 'push');
            app.export_a_a_prime_button.ButtonPushedFcn = createCallbackFcn(app, @export_matrices, true);
            app.export_a_a_prime_button.Text = 'Export Matrices';
            app.constant_vector_matrix = uitable(app.MomentEquationsTab);
            app.constant_vector_matrix.ColumnName = '';
            app.constant_vector_matrix.RowName = {};
            app.mu_c_dis = uibutton(app.MomentEquationsTab, 'push');
            app.mu_c_dis.Icon = 'constant_vec.gif';
            app.mu_c_dis.Text = '';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.Units = 'pixels';
            app.prob_plot = uiaxes(app.ResultsTab);
            title(app.prob_plot, 'Title');
            xlabel(app.prob_plot, 'X');
            ylabel(app.prob_plot, 'Y');
            app.results_table = uitable(app.ResultsTab);
            app.results_table.ColumnName = {'Moment value'; 'Lagrange multiplier value'};
            app.results_table.RowName = {};
            app.export_results_button = uibutton(app.ResultsTab, 'push');
            app.export_results_button.ButtonPushedFcn = createCallbackFcn(app, @export_results, true);
            app.export_results_button.Text = 'Export Results';
            app.PlottingComponentLabel = uilabel(app.ResultsTab);
            app.PlottingComponentLabel.Text = {'Plotting'; 'Component'};
            app.drop_down_menu_for_plot = uidropdown(app.ResultsTab);
            app.drop_down_menu_for_plot.Items = {''};
            app.drop_down_menu_for_plot.Value = '';
            app.plot_new_component = uibutton(app.ResultsTab, 'push');
            app.plot_new_component.ButtonPushedFcn = createCallbackFcn(app, @button_change_plot_pushed, true);
            app.plot_new_component.Text = 'Plot Component';
            app.save_plot_button = uibutton(app.ResultsTab, 'push');
            app.save_plot_button.ButtonPushedFcn = createCallbackFcn(app, @save_plot_button_pushed, true);
            app.save_plot_button.Text = 'Save plot';
            app.export_lagrange_multipliers_button = uibutton(app.ResultsTab, 'push');
            app.export_lagrange_multipliers_button.ButtonPushedFcn = createCallbackFcn(app, @export_lagrange_multipliers_button_pushed, true);
            app.export_lagrange_multipliers_button.Text = {'Export Lagrange'; 'Multipliers Only'};
            app.LineStyleLabel = uilabel(app.ResultsTab);
            app.LineStyleLabel.Text = 'Line Style';
            app.plot_line_type = uidropdown(app.ResultsTab);
            app.plot_line_type.Items = {'Solid line -', 'Dashed line --', 'Dotted line :', 'Dash-dot line -.'};
            app.plot_line_type.Value = 'Solid line -';
            app.LineColorLabel = uilabel(app.ResultsTab);
            app.LineColorLabel.Text = 'Line Color';
            app.plot_line_color = uidropdown(app.ResultsTab);
            app.plot_line_color.Items = {'Blue', 'Black', 'Red', 'Green', 'Yellow', 'Magenta', 'Cyan'};
            app.plot_line_color.Value = 'Blue';
            app.LineSizeEditFieldLabel = uilabel(app.ResultsTab);
            app.LineSizeEditFieldLabel.Text = 'Line Size';
            app.plot_line_size = uieditfield(app.ResultsTab, 'numeric');
            app.plot_line_size.Limits = [0 Inf];
            app.plot_line_size.ValueDisplayFormat = '%11.1g';
            app.plot_line_size.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = zi_closure_scheme()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end